classdef PANEL_STEROWANIAv3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        PanelTab                       matlab.ui.container.Tab
        GridTab                        matlab.ui.container.GridLayout
        PrzejdzdopunktuuruchomsekwencjePanel  matlab.ui.container.Panel
        GridMovetoPanel                matlab.ui.container.GridLayout
        SEND_DATA                      matlab.ui.control.Button
        ZLabel_S                       matlab.ui.control.Label
        YLabel_S                       matlab.ui.control.Label
        XLabel_S                       matlab.ui.control.Label
        Z_send                         matlab.ui.control.NumericEditField
        Y_send                         matlab.ui.control.NumericEditField
        X_send                         matlab.ui.control.NumericEditField
        Move_Lamp                      matlab.ui.control.Lamp
        SekwencjaPanel                 matlab.ui.container.Panel
        GridLayout4                    matlab.ui.container.GridLayout
        SekfencjaSwitch                matlab.ui.control.Switch
        save_sekfencja                 matlab.ui.control.CheckBox
        garabButton                    matlab.ui.control.StateButton
        Logs_text                      matlab.ui.control.TextArea
        KatyPanel                      matlab.ui.container.Panel
        GridPanel                      matlab.ui.container.GridLayout
        J1Label                        matlab.ui.control.Label
        J2Label                        matlab.ui.control.Label
        J1                             matlab.ui.control.Label
        J2                             matlab.ui.control.Label
        AktualnewyliczonepooeniePanel  matlab.ui.container.Panel
        GridWspolrzednePanel           matlab.ui.container.GridLayout
        XLabel_Poz                     matlab.ui.control.Label
        YLabel_Poz                     matlab.ui.control.Label
        ZLabel_Poz                     matlab.ui.control.Label
        X                              matlab.ui.control.Label
        Y                              matlab.ui.control.Label
        Z                              matlab.ui.control.Label
        StanPortuPanel                 matlab.ui.container.Panel
        GridLayout5                    matlab.ui.container.GridLayout
        PortSwitch                     matlab.ui.control.Switch
        Port_Lamp                      matlab.ui.control.Lamp
        SekwencjaRuchwPanel            matlab.ui.container.Panel
        GridLayout3                    matlab.ui.container.GridLayout
        Sekfencja                      matlab.ui.control.Table
        WyczyButton                    matlab.ui.control.Button
        WyczyButton_2                  matlab.ui.control.Button
        StanPanel                      matlab.ui.container.Panel
        GridLayout6                    matlab.ui.container.GridLayout
        L_J1                           matlab.ui.control.Label
        L_J2                           matlab.ui.control.Label
        L_JZ                           matlab.ui.control.Label
        L_Grip                         matlab.ui.control.Label
        KatJ1Label                     matlab.ui.control.Label
        KatJ2Label                     matlab.ui.control.Label
        KatJZLabel                     matlab.ui.control.Label
        ChwytakLabel                   matlab.ui.control.Label
        UstawieniaTab                  matlab.ui.container.Tab
        GridLayout9                    matlab.ui.container.GridLayout
        PredkosilnikwRPMPanel          matlab.ui.container.Panel
        GridLayout7                    matlab.ui.container.GridLayout
        M1EditField_2Label             matlab.ui.control.Label
        RPM_M1                         matlab.ui.control.NumericEditField
        M2EditField_2Label             matlab.ui.control.Label
        RPM_M2                         matlab.ui.control.NumericEditField
        M3EditField_2Label             matlab.ui.control.Label
        RPM_M3                         matlab.ui.control.NumericEditField
        WspczynnikTauPanel             matlab.ui.container.Panel
        GridLayout7_2                  matlab.ui.container.GridLayout
        M1EditFieldLabel               matlab.ui.control.Label
        tau_M1                         matlab.ui.control.NumericEditField
        M2EditFieldLabel               matlab.ui.control.Label
        tau_M2                         matlab.ui.control.NumericEditField
        M3EditFieldLabel               matlab.ui.control.Label
        tau_M3                         matlab.ui.control.NumericEditField
        t1t2Panel                      matlab.ui.container.Panel
        GridLayout8                    matlab.ui.container.GridLayout
        M1t1EditFieldLabel             matlab.ui.control.Label
        t1_M1                          matlab.ui.control.NumericEditField
        M1t2EditFieldLabel             matlab.ui.control.Label
        t2_M1                          matlab.ui.control.NumericEditField
        M2t1EditFieldLabel             matlab.ui.control.Label
        t1_M2                          matlab.ui.control.NumericEditField
        M2t2EditFieldLabel             matlab.ui.control.Label
        t2_M2                          matlab.ui.control.NumericEditField
        M3t1EditFieldLabel             matlab.ui.control.Label
        t1_M3                          matlab.ui.control.NumericEditField
        M3t2EditFieldLabel             matlab.ui.control.Label
        t2_M3                          matlab.ui.control.NumericEditField
        ZapisziwylijButton             matlab.ui.control.Button
    end

    
    properties (Access = private)
        Xr =0; % Description
        Yr =0;
        Zr =0;
        OPEN = 2100;
        CLOSE = 1500;
        grab = 2100;
        grab_text = 'OPEN';
        J1_deg;
        J2_deg;
        JZ_deg;
        UART = [];
        XYZ = [666.66 666.66 666.66];
        string_send;
        device;
        SEND = [];
        SEND_buff = [];
    end
    
    methods (Access = private)
        
        function PORT_SET(app)
            %Uastawienie portu szeregowego
            port = 'COM5';
            Baud_Rate = 115200;
            Data_Bits = 8;
            Stop_Bits = 1;
            %Default for Parity & Flow Control are none.
            app.device = serial(port,'BaudRate',Baud_Rate,'DataBits',Data_Bits,'StopBits',Stop_Bits);
            
            %Zamknięcie portu jeśli jest otwarty
            if ~isempty(instrfind)
                fclose(instrfind);
                app.PortSwitch.Value = "Zamkniety";
            end
        end
        
        function wyslij_odbierz_dane(app,str_send)
            
            fwrite(app.device,str_send);
            data = fread(app.device,50);%odczyt ramki
            data = data';           %transpozycja danych do postaci poziomej
            app.UART = char(data);  %konwersja na znaki ascii
            
            if(app.UART(1) == char('X'))
                cell_XYZ        = split(app.UART);
                app.X.Text      = cell_XYZ{2};
                app.Y.Text      = cell_XYZ{3};
                app.Z.Text      = cell_XYZ{4};
                app.grab        = cell_XYZ{5};
                app.J1.Text    = cell_XYZ{6};
                app.J2.Text    = cell_XYZ{7};
                
                if(app.J1.Text == " 69.00" && app.J2.Text == " 69.00")
                    app.Logs_text.Value = [app.Logs_text.Value(:)', {'- Error! współrzędne poza zasiegiem robota!'}];
                end
                
            end
            if(app.UART(1) == char('M'))
                app.Move_Lamp.Color = [0.00,1.00,0.00];
                cells = split(app.UART);
                app.L_J1.Text = cells{2};
                app.L_J2.Text = cells{3};
                app.L_JZ.Text = cells{4};
                app.L_Grip.Text = cells{5};
                
            else
                app.Move_Lamp.Color = [0.08,0.41,0.08];
            end
            
        end
        
        function main_loop(app)
            app.Logs_text.Value = [app.Logs_text.Value(:)', {'-main'}];
            while app.PortSwitch.Value == "Otwarty"
                app.string_send = sprintf('  %7.2f %7.2f %7.2f %4d \r\n',app.SEND(1),app.SEND(2),app.SEND(3),app.SEND(4));
                wyslij_odbierz_dane(app,app.string_send);
                pause(0.05);
            end
            fclose(instrfind);
        end
        
    end
    
    methods (Access = public)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Logs_text.Value = [app.Logs_text.Value(:)', {'- Start aplikacji'}];
            app.SEND_DATA.Enable = false;
            
            PORT_SET(app);
            
            app.SEND_buff = [app.X_send.Value, app.Y_send.Value, app.Z_send.Value, app.grab];
            app.SEND = app.SEND_buff;
            
            app.Logs_text.Value = [app.Logs_text.Value(:)', {'- Ustawienie portu szeregowego'}];
            
            if app.PortSwitch.Value == "Zamkniety"
                app.Logs_text.Value = [app.Logs_text.Value(:)', {'- Podlacz robota i otworz port'}];
            end
        end

        % Button pushed function: SEND_DATA
        function SEND_DATAButtonPushed(app, event)
            
            if app.garabButton.Value == true
                app.grab = app.CLOSE;
                app.grab_text = 'CLOSE';
            else
                app.grab = app.OPEN;
                app.grab_text = 'OPEN';
            end
            
            if app.save_sekfencja.Value == true
                app.Sekfencja.Data = [app.Sekfencja.Data(:,:);{app.X_send.Value, app.Y_send.Value, app.Z_send.Value,app.grab_text}];
            end
            
            app.SEND_buff = [app.X_send.Value, app.Y_send.Value, app.Z_send.Value,app.grab];
            app.SEND = app.SEND_buff;
            
            app.Logs_text.Value = [app.Logs_text.Value(:)', {'- dane wyslane'}];
            
        end

        % Value changed function: PortSwitch
        function PortSwitchValueChanged(app, event)
            value = app.PortSwitch.Value;
            if value == "Otwarty"
                app.SEND_DATA.Enable = true;
                app.save_sekfencja.Enable = true;
                app.SekfencjaSwitch.Enable = true;
                if ~isempty(instrfind)
                    fclose(instrfind);
                end
                fopen(app.device);%otwarcie portu
                app.Port_Lamp.Color = [1.00,0.00,0.00];
                app.Logs_text.Value = [app.Logs_text.Value(:)', {'- Port otwarty'}];
                pause(1);
                main_loop(app);
            else
                app.SEND_DATA.Enable = false;
                app.save_sekfencja.Enable = false;
                app.SekfencjaSwitch.Enable = false;
                fclose(instrfind);
                app.Port_Lamp.Color = [0.36,0.07,0.07];
                app.Logs_text.Value = [app.Logs_text.Value(:)', {'- Port zamkniety'}];
            end
        end

        % Value changed function: SekfencjaSwitch
        function SekfencjaSwitchValueChanged(app, event)
            stan_start = app.SekfencjaSwitch.Value;
            
            if stan_start == "Start"
                app.save_sekfencja.Enable = false;
                app.string_send = sprintf('-+#############################\r\n');
                app.Logs_text.Value = [app.Logs_text.Value(:)', {'start'}];
            else
                app.save_sekfencja.Enable = true;
                app.string_send = sprintf('--#############################\r\n');
                app.Logs_text.Value = [app.Logs_text.Value(:)', {'stop'}];
            end
            wyslij_odbierz_dane(app,app.string_send);
        end

        % Value changed function: save_sekfencja
        function save_sekfencjaValueChanged(app, event)
            stan_save = app.save_sekfencja.Value;
            
            if stan_save == true
                app.SEND_DATA.BackgroundColor = [0.93,0.69,0.13];
                app.string_send = sprintf('+-#############################\r\n');
                app.Logs_text.Value = [app.Logs_text.Value(:)', {'zapisuj'}];
            else
                app.SEND_DATA.BackgroundColor = [0.4667 0.6745 0.1882];
                app.string_send = sprintf('--#############################\r\n');
                app.Logs_text.Value = [app.Logs_text.Value(:)', {'nie zapisuj'}];
            end
            wyslij_odbierz_dane(app,app.string_send);
        end

        % Button pushed function: WyczyButton_2
        function WyczyButton_2Pushed(app, event)
            app.Logs_text.Value = {''};
        end

        % Button pushed function: ZapisziwylijButton
        function ZapisziwylijButtonPushed(app, event)
            app.string_send = sprintf('  %3d %3.2f %3.2f %3.2f #####M1###\r\n',app.RPM_M1.Value,app.tau_M1.Value,app.t1_M1.Value,app.t2_M1.Value);
            wyslij_odbierz_dane(app,app.string_send);
            pause(0.05);
            app.string_send = sprintf('  %3d %3.2f %3.2f %3.2f #####M2###\r\n',app.RPM_M2.Value,app.tau_M2.Value,app.t1_M2.Value,app.t2_M2.Value);
            wyslij_odbierz_dane(app,app.string_send);
            pause(0.05);
            app.string_send = sprintf('  %3d %3.2f %3.2f %3.2f #####M3###\r\n',app.RPM_M3.Value,app.tau_M3.Value,app.t1_M3.Value,app.t2_M3.Value);
            wyslij_odbierz_dane(app,app.string_send);
            pause(0.05);
            app.Logs_text.Value = [app.Logs_text.Value(:)', {'ustawienia zapisane'}];
        end

        % Button pushed function: WyczyButton
        function WyczyButtonPushed(app, event)
            app.Sekfencja.Data = [];
            app.string_send = sprintf('---############################\r\n');
            wyslij_odbierz_dane(app,app.string_send);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.PanelTab = uitab(app.TabGroup);
            app.PanelTab.Title = 'Panel';
            app.GridTab = uigridlayout(app.PanelTab);
            app.PrzejdzdopunktuuruchomsekwencjePanel = uipanel(app.GridTab);
            app.PrzejdzdopunktuuruchomsekwencjePanel.Title = 'Przejdz do punktu / uruchom sekwencje';
            app.GridMovetoPanel = uigridlayout(app.PrzejdzdopunktuuruchomsekwencjePanel);
            app.SEND_DATA = uibutton(app.GridMovetoPanel, 'push');
            app.SEND_DATA.ButtonPushedFcn = createCallbackFcn(app, @SEND_DATAButtonPushed, true);
            app.SEND_DATA.Enable = 'off';
            app.SEND_DATA.Text = {'Przejdz/'; 'Zapisz punkt'};
            app.ZLabel_S = uilabel(app.GridMovetoPanel);
            app.ZLabel_S.Text = 'Z';
            app.YLabel_S = uilabel(app.GridMovetoPanel);
            app.YLabel_S.Text = 'Y';
            app.XLabel_S = uilabel(app.GridMovetoPanel);
            app.XLabel_S.Text = 'X';
            app.Z_send = uieditfield(app.GridMovetoPanel, 'numeric');
            app.Z_send.Limits = [-150 150];
            app.Z_send.ValueDisplayFormat = '%3.2f';
            app.Z_send.Value = 70;
            app.Y_send = uieditfield(app.GridMovetoPanel, 'numeric');
            app.Y_send.Limits = [-330 330];
            app.Y_send.ValueDisplayFormat = '%3.2f';
            app.X_send = uieditfield(app.GridMovetoPanel, 'numeric');
            app.X_send.Limits = [-330 330];
            app.X_send.ValueDisplayFormat = '%3.2f';
            app.X_send.Value = 330;
            app.Move_Lamp = uilamp(app.GridMovetoPanel);
            app.SekwencjaPanel = uipanel(app.GridMovetoPanel);
            app.SekwencjaPanel.Title = 'Sekwencja';
            app.GridLayout4 = uigridlayout(app.SekwencjaPanel);
            app.SekfencjaSwitch = uiswitch(app.GridLayout4, 'slider');
            app.SekfencjaSwitch.Items = {'Start', 'Stop'};
            app.SekfencjaSwitch.ValueChangedFcn = createCallbackFcn(app, @SekfencjaSwitchValueChanged, true);
            app.SekfencjaSwitch.Enable = 'off';
            app.SekfencjaSwitch.Value = 'Stop';
            app.save_sekfencja = uicheckbox(app.GridLayout4);
            app.save_sekfencja.ValueChangedFcn = createCallbackFcn(app, @save_sekfencjaValueChanged, true);
            app.save_sekfencja.Enable = 'off';
            app.save_sekfencja.Text = 'Zapisuj punkty sekwencji';
            app.garabButton = uibutton(app.GridMovetoPanel, 'state');
            app.garabButton.Text = 'Złap';
            app.Logs_text = uitextarea(app.GridTab);
            app.KatyPanel = uipanel(app.GridTab);
            app.KatyPanel.Title = 'Katy';
            app.GridPanel = uigridlayout(app.KatyPanel);
            app.J1Label = uilabel(app.GridPanel);
            app.J1Label.Text = 'J1';
            app.J2Label = uilabel(app.GridPanel);
            app.J2Label.Text = 'J2';
            app.J1 = uilabel(app.GridPanel);
            app.J1.Text = '--';
            app.J2 = uilabel(app.GridPanel);
            app.J2.Text = '--';
            app.AktualnewyliczonepooeniePanel = uipanel(app.GridTab);
            app.AktualnewyliczonepooeniePanel.Title = 'Aktualne wyliczone położenie';
            app.GridWspolrzednePanel = uigridlayout(app.AktualnewyliczonepooeniePanel);
            app.XLabel_Poz = uilabel(app.GridWspolrzednePanel);
            app.XLabel_Poz.Text = 'X';
            app.YLabel_Poz = uilabel(app.GridWspolrzednePanel);
            app.YLabel_Poz.Text = 'Y';
            app.ZLabel_Poz = uilabel(app.GridWspolrzednePanel);
            app.ZLabel_Poz.Text = 'Z';
            app.X = uilabel(app.GridWspolrzednePanel);
            app.X.Text = '---';
            app.Y = uilabel(app.GridWspolrzednePanel);
            app.Y.Text = '---';
            app.Z = uilabel(app.GridWspolrzednePanel);
            app.Z.Text = '---';
            app.StanPortuPanel = uipanel(app.GridTab);
            app.StanPortuPanel.Title = 'Stan Portu';
            app.GridLayout5 = uigridlayout(app.StanPortuPanel);
            app.PortSwitch = uiswitch(app.GridLayout5, 'slider');
            app.PortSwitch.Items = {'Zamkniety', 'Otwarty'};
            app.PortSwitch.ValueChangedFcn = createCallbackFcn(app, @PortSwitchValueChanged, true);
            app.PortSwitch.Value = 'Zamkniety';
            app.Port_Lamp = uilamp(app.GridLayout5);
            app.SekwencjaRuchwPanel = uipanel(app.GridTab);
            app.SekwencjaRuchwPanel.Title = 'Sekwencja Ruchów';
            app.GridLayout3 = uigridlayout(app.SekwencjaRuchwPanel);
            app.Sekfencja = uitable(app.GridLayout3);
            app.Sekfencja.ColumnName = {'X'; 'Y'; 'Z'; 'Zlap'};
            app.Sekfencja.RowName = {};
            app.WyczyButton = uibutton(app.GridLayout3, 'push');
            app.WyczyButton.ButtonPushedFcn = createCallbackFcn(app, @WyczyButtonPushed, true);
            app.WyczyButton.Text = 'Wyczyść';
            app.WyczyButton_2 = uibutton(app.GridTab, 'push');
            app.WyczyButton_2.ButtonPushedFcn = createCallbackFcn(app, @WyczyButton_2Pushed, true);
            app.WyczyButton_2.Text = 'Wyczyść';
            app.StanPanel = uipanel(app.GridTab);
            app.StanPanel.Title = 'Stan';
            app.GridLayout6 = uigridlayout(app.StanPanel);
            app.L_J1 = uilabel(app.GridLayout6);
            app.L_J1.Text = '0.00';
            app.L_J2 = uilabel(app.GridLayout6);
            app.L_J2.Text = '0.00';
            app.L_JZ = uilabel(app.GridLayout6);
            app.L_JZ.Text = '0.00';
            app.L_Grip = uilabel(app.GridLayout6);
            app.L_Grip.Text = '-';
            app.KatJ1Label = uilabel(app.GridLayout6);
            app.KatJ1Label.Text = 'Kat J1';
            app.KatJ2Label = uilabel(app.GridLayout6);
            app.KatJ2Label.Text = 'Kat J2';
            app.KatJZLabel = uilabel(app.GridLayout6);
            app.KatJZLabel.Text = 'Kat JZ';
            app.ChwytakLabel = uilabel(app.GridLayout6);
            app.ChwytakLabel.Text = 'Chwytak';
            app.UstawieniaTab = uitab(app.TabGroup);
            app.UstawieniaTab.Title = 'Ustawienia';
            app.GridLayout9 = uigridlayout(app.UstawieniaTab);
            app.PredkosilnikwRPMPanel = uipanel(app.GridLayout9);
            app.PredkosilnikwRPMPanel.Title = 'Predkość silników [RPM]';
            app.GridLayout7 = uigridlayout(app.PredkosilnikwRPMPanel);
            app.M1EditField_2Label = uilabel(app.GridLayout7);
            app.M1EditField_2Label.Text = 'M1';
            app.RPM_M1 = uieditfield(app.GridLayout7, 'numeric');
            app.RPM_M1.Limits = [0 1000];
            app.RPM_M1.Value = 5;
            app.M2EditField_2Label = uilabel(app.GridLayout7);
            app.M2EditField_2Label.Text = 'M2';
            app.RPM_M2 = uieditfield(app.GridLayout7, 'numeric');
            app.RPM_M2.Limits = [0 1000];
            app.RPM_M2.Value = 10;
            app.M3EditField_2Label = uilabel(app.GridLayout7);
            app.M3EditField_2Label.Text = 'M3';
            app.RPM_M3 = uieditfield(app.GridLayout7, 'numeric');
            app.RPM_M3.Limits = [0 1000];
            app.RPM_M3.Value = 400;
            app.WspczynnikTauPanel = uipanel(app.GridLayout9);
            app.WspczynnikTauPanel.Title = 'Współczynnik Tau';
            app.GridLayout7_2 = uigridlayout(app.WspczynnikTauPanel);
            app.M1EditFieldLabel = uilabel(app.GridLayout7_2);
            app.M1EditFieldLabel.Text = 'M1';
            app.tau_M1 = uieditfield(app.GridLayout7_2, 'numeric');
            app.M2EditFieldLabel = uilabel(app.GridLayout7_2);
            app.M2EditFieldLabel.Text = 'M2';
            app.tau_M2 = uieditfield(app.GridLayout7_2, 'numeric');
            app.M3EditFieldLabel = uilabel(app.GridLayout7_2);
            app.M3EditFieldLabel.Text = 'M3';
            app.tau_M3 = uieditfield(app.GridLayout7_2, 'numeric');
            app.tau_M3.Value = 0.5;
            app.t1t2Panel = uipanel(app.GridLayout9);
            app.t1t2Panel.Title = 't1/t2';
            app.GridLayout8 = uigridlayout(app.t1t2Panel);
            app.M1t1EditFieldLabel = uilabel(app.GridLayout8);
            app.M1t1EditFieldLabel.Text = 'M1 - t1';
            app.t1_M1 = uieditfield(app.GridLayout8, 'numeric');
            app.t1_M1.Value = 0.4;
            app.M1t2EditFieldLabel = uilabel(app.GridLayout8);
            app.M1t2EditFieldLabel.Text = 'M1 - t2';
            app.t2_M1 = uieditfield(app.GridLayout8, 'numeric');
            app.t2_M1.Value = 0.6;
            app.M2t1EditFieldLabel = uilabel(app.GridLayout8);
            app.M2t1EditFieldLabel.Text = 'M2 - t1';
            app.t1_M2 = uieditfield(app.GridLayout8, 'numeric');
            app.t1_M2.Value = 0.4;
            app.M2t2EditFieldLabel = uilabel(app.GridLayout8);
            app.M2t2EditFieldLabel.Text = 'M2 - t2';
            app.t2_M2 = uieditfield(app.GridLayout8, 'numeric');
            app.t2_M2.Value = 0.6;
            app.M3t1EditFieldLabel = uilabel(app.GridLayout8);
            app.M3t1EditFieldLabel.Text = 'M3 - t1';
            app.t1_M3 = uieditfield(app.GridLayout8, 'numeric');
            app.t1_M3.Value = 0.1;
            app.M3t2EditFieldLabel = uilabel(app.GridLayout8);
            app.M3t2EditFieldLabel.Text = 'M3 - t2';
            app.t2_M3 = uieditfield(app.GridLayout8, 'numeric');
            app.t2_M3.Value = 0.9;
            app.ZapisziwylijButton = uibutton(app.GridLayout9, 'push');
            app.ZapisziwylijButton.ButtonPushedFcn = createCallbackFcn(app, @ZapisziwylijButtonPushed, true);
            app.ZapisziwylijButton.Text = 'Zapisz i wyślij';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PANEL_STEROWANIAv3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end